classdef graph1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        offsetSliderLabel        matlab.ui.control.Label
        offsetSlider             matlab.ui.control.Slider
        yscaleKnobLabel          matlab.ui.control.Label
        yscaleKnob               matlab.ui.control.DiscreteKnob
        timescaleKnobLabel       matlab.ui.control.Label
        timescaleKnob            matlab.ui.control.DiscreteKnob
        startingtimeSliderLabel  matlab.ui.control.Label
        startingtimeSlider       matlab.ui.control.Slider
        stopButton               matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function [] = pushValuesChangedButton(app)
            app.yscaleKnobValueChanged;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: offsetSlider, startingtimeSlider, 
        % timescaleKnob, yscaleKnob
        function yscaleKnobValueChanged(app, event)
            
            yscale = str2double(app.yscaleKnob.Value);
            timescale = str2double(app.timescaleKnob.Value);
            offset = app.offsetSlider.Value;
            startingtime = app.startingtimeSlider.Value;
            ylim(app.UIAxes, [-yscale+offset, yscale+offset])
            xlim(app.UIAxes, [startingtime, startingtime+timescale])
            if yscale > 100
                app.offsetSlider.Limits = [-yscale, yscale];
            else
                app.offsetSlider.Limits = [-100, 100];
            end
            
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            global DAQPARS
            
            if DAQPARS.MainApp.Recording
                pushStartButton(DAQPARS.MainApp)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'graph1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph 1')
            xlabel(app.UIAxes, 'msec')
            ylabel(app.UIAxes, 'mV or pA')
            app.offsetSliderLabel = uilabel(app.UIFigure);
            app.offsetSliderLabel.Text = 'offset';
            app.offsetSlider = uislider(app.UIFigure);
            app.offsetSlider.Limits = [-100 100];
            app.offsetSlider.ValueChangedFcn = createCallbackFcn(app, @yscaleKnobValueChanged, true);
            app.yscaleKnobLabel = uilabel(app.UIFigure);
            app.yscaleKnobLabel.Text = 'y scale';
            app.yscaleKnob = uiknob(app.UIFigure, 'discrete');
            app.yscaleKnob.Items = {'5', '20', '50', '100', '500', '1000'};
            app.yscaleKnob.ValueChangedFcn = createCallbackFcn(app, @yscaleKnobValueChanged, true);
            app.yscaleKnob.Value = '50';
            app.timescaleKnobLabel = uilabel(app.UIFigure);
            app.timescaleKnobLabel.Text = 'time scale';
            app.timescaleKnob = uiknob(app.UIFigure, 'discrete');
            app.timescaleKnob.Items = {'10', '100', '1000', '10000', '100000'};
            app.timescaleKnob.ValueChangedFcn = createCallbackFcn(app, @yscaleKnobValueChanged, true);
            app.timescaleKnob.Value = '1000';
            app.startingtimeSliderLabel = uilabel(app.UIFigure);
            app.startingtimeSliderLabel.Text = 'starting time';
            app.startingtimeSlider = uislider(app.UIFigure);
            app.startingtimeSlider.ValueChangedFcn = createCallbackFcn(app, @yscaleKnobValueChanged, true);
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end