classdef protocolsequence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        skiptonextButton              matlab.ui.control.Button
        savesequenceButton            matlab.ui.control.Button
        loadsequenceButton            matlab.ui.control.Button
        stopprotocolButton            matlab.ui.control.Button
        runallButton                  matlab.ui.control.Button
        outputsDropDown_5             matlab.ui.control.DropDown
        outputsDropDown_5Label        matlab.ui.control.Label
        parametersDropDown_5          matlab.ui.control.DropDown
        parametersDropDown_5Label     matlab.ui.control.Label
        run5Button                    matlab.ui.control.Button
        outputsDropDown_4             matlab.ui.control.DropDown
        outputsDropDown_4Label        matlab.ui.control.Label
        parametersDropDown_4          matlab.ui.control.DropDown
        parametersDropDown_4Label     matlab.ui.control.Label
        run4Button                    matlab.ui.control.Button
        run3Button                    matlab.ui.control.Button
        run2Button                    matlab.ui.control.Button
        run1Button                    matlab.ui.control.Button
        outputsDropDown_3             matlab.ui.control.DropDown
        outputsDropDown_3Label        matlab.ui.control.Label
        parametersDropDown_3          matlab.ui.control.DropDown
        parametersDropDown_3Label     matlab.ui.control.Label
        outputsDropDown_2             matlab.ui.control.DropDown
        outputsDropDown_2Label        matlab.ui.control.Label
        parametersDropDown_2          matlab.ui.control.DropDown
        parametersDropDown_2Label     matlab.ui.control.Label
        chooseoutputsfolderButton     matlab.ui.control.Button
        outputsDropDown_1             matlab.ui.control.DropDown
        outputsDropDownLabel          matlab.ui.control.Label
        parametersDropDown_1          matlab.ui.control.DropDown
        parametersDropDownLabel       matlab.ui.control.Label
        chooseparametersfolderButton  matlab.ui.control.Button
        protocolsequenceLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % LCSMS_patchclamp
        ParametersFolder % folder with parameter files
        OutputsFolder % folder with output files
        StopCalled % stop a sequence
        SequenceRunning % is a protocol sequence in progress?
        CurrentProtocol % number identifier of protocol now running
    end
    
    methods (Access = private)
        
        function [] = populatefields(app,flag,pValue,oValue)
            parameterFiles = dir([app.ParametersFolder,filesep,'*mat']);
            outputFiles = dir([app.OutputsFolder,filesep,'*mat']);
            foo = struct2cell(parameterFiles);
            foo = foo(1,:);
            parameterFiles = [{'None'},foo];
            goo = struct2cell(outputFiles);
            goo = goo(1,:);
            outputFiles = [{'None'},goo];
            if flag < 4 % not a saved sequence
                for ii = 1:5
                    if flag ~= 3 % outputs only
                        app.(['parametersDropDown_',num2str(ii)]).Items = parameterFiles;
                        app.(['parametersDropDown_',num2str(ii)]).Value = app.(['parametersDropDown_',num2str(ii)]).Items{1};
                    end
                    if flag ~= 2 % parameters only
                        app.(['outputsDropDown_',num2str(ii)]).Items = outputFiles;
                        app.(['outputsDropDown_',num2str(ii)]).Value = app.(['outputsDropDown_',num2str(ii)]).Items{1};
                    end
                end
            else
                for ii = 1:5
                    app.(['parametersDropDown_',num2str(ii)]).Items = parameterFiles;
                    app.(['parametersDropDown_',num2str(ii)]).Value = pValue{ii};
                    app.(['outputsDropDown_',num2str(ii)]).Items = outputFiles;
                    app.(['outputsDropDown_',num2str(ii)]).Value = oValue{ii};
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global DAQPARS 
            
            app.CallingApp = mainapp;
            app.ParametersFolder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'parameters'];
            app.OutputsFolder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'outputs'];
            populatefields(app,1)
            app.StopCalled = false;
            app.SequenceRunning = false;
            app.CurrentProtocol = 1;
            
        end

        % Button pushed function: chooseparametersfolderButton
        function chooseparametersfolderButtonPushed(app, event)
            
            newPath = uigetdir(app.ParametersFolder,'Choose a directory');
            if newPath
                app.ParametersFolder = newPath;
                populatefields(app,2)
            end
            
            
        end

        % Button pushed function: chooseoutputsfolderButton
        function chooseoutputsfolderButtonPushed(app, event)
                        
            newPath = uigetdir(app.OutputsFolder,'Choose a directory');
            if newPath
                app.OutputsFolder = newPath;
                populatefields(app,3)
            end
            
        end

        % Callback function
        function run1ButtonPushed(app, event)
            global DAQPARS 

            if app.CallingApp.Recording
                return
            end
            lineNum = event.Source.Text(end);
            pFile = app.(['parametersDropDown_',lineNum]).Value;
            oFile = app.(['outputsDropDown_',lineNum]).Value;
            if strcmp(pFile,'None') || strcmp(oFile,'None')
                return
            end
            pFile = [app.ParametersFolder,filesep,pFile];
            oFile = [app.OutputsFolder,filesep,oFile];
            try
                load(pFile,'status','gain','holding')
                foo = ~isempty(setdiff(status,DAQPARS.channelStatus));
                boo = any(DAQPARS.channelGain ~= gain);
                bar = any(DAQPARS.channelHolding ~= holding);
                if foo || boo || bar
                    loadparameters(pFile)
                    pushWriteMulticlampButton(app.CallingApp)    
                end
            catch
                warndlg('Check parameter file','RUN ERROR')               
            end
            try
                loadoutputs(oFile)
            catch
                warndlg('Check output file','RUN ERROR')
            end
            app.CurrentProtocol = str2double(lineNum);
            pushStartButton(app.CallingApp);
        end

        % Button pushed function: run1Button, run2Button, run3Button, 
        % run4Button, run5Button, runallButton
        function runallButtonPushed(app, event)
            if app.CallingApp.Recording
                return
            end 
            if event.Source == app.runallButton
                app.SequenceRunning = true;
                protocols = 1:5;
            else
                app.SequenceRunning = false;
                lineNum = event.Source.Text(end);
                lineNo = str2double(lineNum);
                protocols = lineNo:5;
            end
            for ii = protocols
                if app.StopCalled
                    app.StopCalled = false;
                    break
                end
                if ~app.SequenceRunning && ii>protocols(1)
                    break
                end
                foo.Source.Text = ['all ',num2str(ii)];
                run1ButtonPushed(app,foo)
                while app.CallingApp.Recording
                    drawnow limitrate
                end
            end
            app.SequenceRunning = false;
            
        end

        % Button pushed function: stopprotocolButton
        function stopprotocolButtonPushed(app, event)
            app.StopCalled = true;
            if app.CallingApp.Recording
                pushStartButton(app.CallingApp); % stop current recording
            end
            if ~app.SequenceRunning
                app.StopCalled = false;
            end
        end

        % Button pushed function: skiptonextButton
        function skiptonextButtonPushed(app, event)
            if ~app.SequenceRunning
                app.SequenceRunning = true;
            end
            if app.CallingApp.Recording
                pushStartButton(app.CallingApp) % stop current recording
            end

        end

        % Button pushed function: savesequenceButton
        function savesequenceButtonPushed(app, event)
            global DAQPARS
            out.parFolder = app.ParametersFolder;
            out.outputsFolder = app.OutputsFolder;
            for ii = 1:5
                out.parFileValue{ii} = app.(['parametersDropDown_',num2str(ii)]).Value;
                out.outputFileValue{ii} = app.(['outputsDropDown_',num2str(ii)]).Value;
            end
            folder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'sequences',filesep];
            if ~exist(folder,'Dir')
                mkdir(folder)
            end
            [fileName,filePath] = ...
                uiputfile([folder,'*.mat'],'Enter file name to save protocol sequence.');
            if fileName
                save([filePath,fileName],'-struct','out')
            end
            
        end

        % Button pushed function: loadsequenceButton
        function loadsequenceButtonPushed(app, event)
            global DAQPARS %#ok<GVMIS> 
            
            folder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'sequences',filesep];
            [fileName,filePath] = uigetfile([folder,'*.mat']);
            load([filePath,fileName],'outputFileValue','outputsFolder',...
                'parFileValue','parFolder');
            app.ParametersFolder = parFolder;
            app.OutputsFolder = outputsFolder;
            try
                populatefields(app,4,parFileValue,outputFileValue)
            catch
                try
                    app.OutputsFolder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'outputs'];
                    app.ParametersFolder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'parameters'];
                    populatefields(app,4,parFileValue,outputFileValue)
                catch
                    foo = parFolder;
                    boo = strfind(foo,'parameters');
                    boo = boo + 11;
                    app.ParametersFolder = [DAQPARS.daqFolder,filesep,'user_files',filesep,'parameters',filesep,foo(boo:end)];
                    foo = outputsFolder;
                    boo = strfind(foo,'outputs');
                    boo = boo + 8;
                    app.OutputsFolder = [DAQPARS.daqfolder,filesep,'user_files',filesep,'outputs',filesep,foo(boo:end)];
                    populatefields(app,4,parFileValue,outputFileValue) 
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.protocolsequenceLabel = uilabel(app.UIFigure);
            app.protocolsequenceLabel.Text = 'protocol sequence';
            app.chooseparametersfolderButton = uibutton(app.UIFigure, 'push');
            app.chooseparametersfolderButton.ButtonPushedFcn = createCallbackFcn(app, @chooseparametersfolderButtonPushed, true);
            app.chooseparametersfolderButton.Text = 'choose parameters folder';
            app.parametersDropDownLabel = uilabel(app.UIFigure);
            app.parametersDropDownLabel.Text = 'parameters';
            app.parametersDropDown_1 = uidropdown(app.UIFigure);
            app.parametersDropDown_1.Items = {'None'};
            app.parametersDropDown_1.Value = 'None';
            app.outputsDropDownLabel = uilabel(app.UIFigure);
            app.outputsDropDownLabel.Text = 'outputs';
            app.outputsDropDown_1 = uidropdown(app.UIFigure);
            app.outputsDropDown_1.Items = {'None'};
            app.outputsDropDown_1.Value = 'None';
            app.chooseoutputsfolderButton = uibutton(app.UIFigure, 'push');
            app.chooseoutputsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @chooseoutputsfolderButtonPushed, true);
            app.chooseoutputsfolderButton.Text = 'choose outputs folder';
            app.parametersDropDown_2Label = uilabel(app.UIFigure);
            app.parametersDropDown_2Label.Text = 'parameters';
            app.parametersDropDown_2 = uidropdown(app.UIFigure);
            app.parametersDropDown_2.Items = {'None'};
            app.parametersDropDown_2.Value = 'None';
            app.outputsDropDown_2Label = uilabel(app.UIFigure);
            app.outputsDropDown_2Label.Text = 'outputs';
            app.outputsDropDown_2 = uidropdown(app.UIFigure);
            app.outputsDropDown_2.Items = {'None'};
            app.outputsDropDown_2.Value = 'None';
            app.parametersDropDown_3Label = uilabel(app.UIFigure);
            app.parametersDropDown_3Label.Text = 'parameters';
            app.parametersDropDown_3 = uidropdown(app.UIFigure);
            app.parametersDropDown_3.Items = {'None'};
            app.parametersDropDown_3.Value = 'None';
            app.outputsDropDown_3Label = uilabel(app.UIFigure);
            app.outputsDropDown_3Label.Text = 'outputs';
            app.outputsDropDown_3 = uidropdown(app.UIFigure);
            app.outputsDropDown_3.Items = {'None'};
            app.outputsDropDown_3.Value = 'None';
            app.run1Button = uibutton(app.UIFigure, 'push');
            app.run1Button.ButtonPushedFcn = createCallbackFcn(app, @runallButtonPushed, true);
            app.run1Button.Text = 'run 1';
            app.run2Button = uibutton(app.UIFigure, 'push');
            app.run2Button.ButtonPushedFcn = createCallbackFcn(app, @runallButtonPushed, true);
            app.run2Button.Text = 'run 2';
            app.run3Button = uibutton(app.UIFigure, 'push');
            app.run3Button.ButtonPushedFcn = createCallbackFcn(app, @runallButtonPushed, true);
            app.run3Button.Text = 'run 3';
            app.run4Button = uibutton(app.UIFigure, 'push');
            app.run4Button.ButtonPushedFcn = createCallbackFcn(app, @runallButtonPushed, true);
            app.run4Button.Text = 'run 4';
            app.parametersDropDown_4Label = uilabel(app.UIFigure);
            app.parametersDropDown_4Label.Text = 'parameters';
            app.parametersDropDown_4 = uidropdown(app.UIFigure);
            app.parametersDropDown_4.Items = {'None'};
            app.parametersDropDown_4.Value = 'None';
            app.outputsDropDown_4Label = uilabel(app.UIFigure);
            app.outputsDropDown_4Label.Text = 'outputs';
            app.outputsDropDown_4 = uidropdown(app.UIFigure);
            app.outputsDropDown_4.Items = {'None'};
            app.outputsDropDown_4.Value = 'None';
            app.run5Button = uibutton(app.UIFigure, 'push');
            app.run5Button.ButtonPushedFcn = createCallbackFcn(app, @runallButtonPushed, true);
            app.run5Button.Text = 'run 5';
            app.parametersDropDown_5Label = uilabel(app.UIFigure);
            app.parametersDropDown_5Label.Text = 'parameters';
            app.parametersDropDown_5 = uidropdown(app.UIFigure);
            app.parametersDropDown_5.Items = {'None'};
            app.parametersDropDown_5.Value = 'None';
            app.outputsDropDown_5Label = uilabel(app.UIFigure);
            app.outputsDropDown_5Label.Text = 'outputs';
            app.outputsDropDown_5 = uidropdown(app.UIFigure);
            app.outputsDropDown_5.Items = {'None'};
            app.outputsDropDown_5.Value = 'None';
            app.runallButton = uibutton(app.UIFigure, 'push');
            app.runallButton.ButtonPushedFcn = createCallbackFcn(app, @runallButtonPushed, true);
            app.runallButton.Text = 'run all';
            app.stopprotocolButton = uibutton(app.UIFigure, 'push');
            app.stopprotocolButton.ButtonPushedFcn = createCallbackFcn(app, @stopprotocolButtonPushed, true);
            app.stopprotocolButton.Text = 'stop protocol';
            app.loadsequenceButton = uibutton(app.UIFigure, 'push');
            app.loadsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @loadsequenceButtonPushed, true);
            app.loadsequenceButton.Text = 'load sequence';
            app.savesequenceButton = uibutton(app.UIFigure, 'push');
            app.savesequenceButton.ButtonPushedFcn = createCallbackFcn(app, @savesequenceButtonPushed, true);
            app.savesequenceButton.Text = 'save sequence';
            app.skiptonextButton = uibutton(app.UIFigure, 'push');
            app.skiptonextButton.ButtonPushedFcn = createCallbackFcn(app, @skiptonextButtonPushed, true);
            app.skiptonextButton.Text = 'skip to next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = protocolsequence(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end